classdef MewRecorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Mn_Setting                matlab.ui.container.Menu
        Mn_SetMaxTimeOut          matlab.ui.container.Menu
        Mn_SelectAudioDevice      matlab.ui.container.Menu
        Mn_AudioSettings          matlab.ui.container.Menu
        Mn_Tools                  matlab.ui.container.Menu
        Mn_ConvertTVDtoMP4viaAVI  matlab.ui.container.Menu
        Mn_LoadEchoWavePreset     matlab.ui.container.Menu
        Mn_Info                   matlab.ui.container.Menu
        Mn_AudioInfo              matlab.ui.container.Menu
        Mn_CheckFFMPEG            matlab.ui.container.Menu
        Mn_About                  matlab.ui.container.Menu
        Lp_AudSig1                matlab.ui.control.Lamp
        Lp_AudSig2                matlab.ui.control.Lamp
        Lp_AudSig3                matlab.ui.control.Lamp
        Lp_AudSig4                matlab.ui.control.Lamp
        Bn_Record                 matlab.ui.control.StateButton
        Lb_AudioSettings          matlab.ui.control.Label
        Ck_ifRecordTelemed        matlab.ui.control.CheckBox
        Ed_RecordDur              matlab.ui.control.NumericEditField
        Ck_RecordByDur            matlab.ui.control.CheckBox
        Lb_MaxTimeOut             matlab.ui.control.Label
        CurrentfolderLabel        matlab.ui.control.Label
        Bn_SelectOutputFolder     matlab.ui.control.Button
        Ck_ifNormAudio            matlab.ui.control.CheckBox
        Ed_TimeRecorded           matlab.ui.control.EditField
        TimeRecordedLabel         matlab.ui.control.Label
        Pn_MsgBox                 matlab.ui.container.Panel
        Msg1                      matlab.ui.control.HTML
        Bn_Stop                   matlab.ui.control.Button
        Pn_Filename               matlab.ui.container.Panel
        Ed_Annotate               matlab.ui.control.EditField
        AnnotateEditFieldLabel    matlab.ui.control.Label
        Ed_Filename               matlab.ui.control.EditField
        FilenameLabel             matlab.ui.control.Label
        Ck_AppendTime             matlab.ui.control.CheckBox
        Ck_AppendDate             matlab.ui.control.CheckBox
        Ed_FilePrefix             matlab.ui.control.EditField
        FilenameprefixLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppName = 'MewRecorder';
        Version = '1.4.1'; Update = '14FEB2026';  % Update version
        minMatlabVer = '9.13'; % R2022b
        minMatlabVerName = 'R2022b'; 
        logFilename = '_MewRecorder.log';
        status = 'inactive'; % status: inactive, ready, busy
        flag_busy = false;
        maxTimeOut = 7200; % max time out in seconds. 
        isRecording = false; % flag for recoding status
        ifProbeConnected = false; 
        ffmpegFilename = 'ffmpeg.zip'; ffmpegFound = false; ffmpegFullPathFilename = ''; ffmpegVersion = '';
        OutputPath = pwd; appPath = []; appInstalledPath = [];
        appStartUpTime = []; recordStartTime = []; recordStopTime = [];  recordStartTimeStr = ''; recordStopTimeStr = ''
        audioExt = '.wav'; tvdExt = '.tvd';
        audio_dev_idx = 0; audio_dev_name ='';   % Default audio device = 0
        audio_srate = 44100;  num_audio_channels = 2;  audio_bit_rate = 16; 
        max_audio_sig_lamps = 4; % max # of lamps shown in the audio panel
        which_channel_is_telemed_sync = 2; 
        recordingTimer = []; timerPeriod = 0.070;      
        save_file_prefix = ''; FullPathFilename = ''; AudioFullPathFilename = ''; TvdFullPathFilename = ''; 
        audioRecObj = []; asmcmd = []; EchoWavePath = []; EchoWaveCmd = [];
        dateFormat = 'yyyy-MM-dd'; timeFormat = 'HH-mm-ss-SSS';
        tmpFolder = '_mwr_tmp_mwr'; tmpFile = '_mwr_tmp_mwr_test.txt';
        %% Telemed ID:
        id_scanning_state_set = 201; id_scanning_state_get = 200;
        id_state_b_b1r = 1; % B-mode scan running
        id_state_b_b1f = 2;  % B-mode frozen
        %% colors:
        hasSignalColor = [1, 0, 0];
        noSignalColor = [0, 0, 0];
    end
    
    methods (Access = private)
       %%
       function [success, msg] = initialize(app)
            success = true; msg = '';  msg1 = ''; msg2 = ''; msg3 = '';
            app.Mn_ConvertTVDtoMP4viaAVI.UserData.toFormat = 'avi_comp';
            if ~app.isAdminPC, toggleEchoWaveFunctions(app, 'off');end
            %% Init EchoWave:                       
            if app.Ck_ifRecordTelemed.Value              
                [success, msg1] = DetectEchoWave(app); 
                if success && app.ifProbeConnected, EchoWaveFreeze(app);end
            end 
            %% Initialize audio device: 
            if success
                [success, msg2] = selectAudioDevice(app, app.audio_dev_idx);
            end
            if success
                [success, msg3] = CheckIfAudioDeviceSettingSupported(app);
            end 
            % %% If not success:
            msg = sprintf('%s %s %s', msg1, msg2, msg3);
            if success
                appSetStatus(app, 'ready');
                app.Msg1.HTMLSource = sprintf('<p> %s <br> %s </p>', app.Msg1.HTMLSource, msg); 
            else
                appSetStatus(app, 'inactive');
                app.Msg1.HTMLSource = sprintf('<p> %s </p>', msg); 
                return; 
            end
       end %initialize
       %% 
       function [success, msg] = CheckOutputFolderAccess(app, folderPath)
           if nargin < 2 || isempty(folderPath), folderPath = app.OutputPath;end
           tmpFileFullPath = fullfile(folderPath, app.tmpFile);
           fid = -1; success = false;
           [fid, msg]=fopen(tmpFileFullPath,'w');
           if fid < 0
               success = false;  msg = 'Folder access denied';
           else
               fclose(fid); delete(tmpFileFullPath); success = true;
           end
       end %CheckOutputFolderAccess
       %%
        function EchoWaveStartRecord(app)
            % freeze ultrasound scanning
            % if (app.asmcmd.IsRunState() == 1), app.asmcmd.FreezeRun(); end
            % % pause cine playback
            % if (app.asmcmd.IsPlayState() == 1), app.asmcmd.PlayPause(); end
            %Start recording:
           app.asmcmd.ParamSet(app.id_scanning_state_set, app.id_state_b_b1r); % B-mode scan running
        end %EchoWaveStartRecord(asmcmd)
        %%
        function EchoWaveStopAndSave(app)
            % id_scanning_state_set = 201; id_scanning_state_get = 200;
            % id_state_b_b1r = 1; % B-mode scan running
            % id_state_b_b1f = 2;  % B-mode frozen
            saveCineCmd = [app.EchoWaveCmd ' -save_cine tvd "' app.TvdFullPathFilename '"']; % must specify absolute path for the output  file.
            % stop recording
            % if (app.asmcmd.IsRecordingState() == 1), app.asmcmd.RecordStop(); end
            % freeze ultrasound scanning
            EchoWaveFreeze(app);
            % pause cine playback
            % if (app.asmcmd.IsPlayState() == 1), app.asmcmd.PlayPause(); end
            % save TVD file
            [status,cmdout] = system(saveCineCmd, '-echo');  % It roughly takes 1 s to save 5 s video; takes 5 s to save 5 min video.
            % tic
            % nFrames = app.asmcmd.GetFramesCount();
            % toc
        end %EchoWaveStopRecordAndSave()  
        
        function success = SaveAudio(app)
            success = false;
            try 
                if isrecording(app.audioRecObj), stop(app.audioRecObj);end
                s = getaudiodata(app.audioRecObj);
                delete(app.audioRecObj);
            catch
                delete(app.audioRecObj);
            end
            nChannels = size(s, 2);
            if app.Ck_ifNormAudio.Value
                for i = 1:nChannels
                    s1 = s(:,i); s1 = s1 - mean(s1); 
                    s1 = s1 ./ max(abs(s1)); s(:,i) = s1;
                end
            end
            try
	            audiowrite(app.AudioFullPathFilename, s , app.audio_srate);
            catch
                success = false;  return;
            end
            success = true;
        end %SaveAudio
        %%
        function appSetStatus(app, status)
            busy = false; active = false;
            %% Disable the following functions/buttons when busy (enable them when ready):
            funcsToBeDisabledIfBusy = ...
                {app.Bn_Record; app.Ed_FilePrefix; app.Mn_Setting; ...
                 app.Bn_SelectOutputFolder; app.Ed_RecordDur; app.Ck_RecordByDur; ...
                 app.Pn_Filename; app.Mn_Setting; app.Mn_Info; ...
                 app.Ck_ifNormAudio; app.Ck_ifRecordTelemed; app.Mn_Tools; ...
                 app.Ck_AppendDate; app.Ck_AppendTime
                };
            %%
            funcsToBeDisabledIfInactive = ...
                {app.Bn_Record; app.Bn_Stop; app.Mn_Tools; app.Mn_Info; ...
                 app.Ed_RecordDur; app.Ck_RecordByDur;  app.Ed_FilePrefix;  ...
                 app.Ck_AppendDate; app.Ck_AppendTime ...
                };
            %%
            if strcmpi(status, 'busy')
                active = true; busy = true; app.status = 'busy';               
            elseif strcmpi(status, 'ready')
                active = true; busy = false; app.status = 'ready';  
                app.Msg1.HTMLSource = '<p>Ready.</p>';
            elseif strcmpi(status, 'inactive')  
                active = false; busy = false; app.status = 'inactive';  
            end
            if busy
                app.flag_busy = true;
                for i = 1:numel(funcsToBeDisabledIfBusy), funcsToBeDisabledIfBusy{i}.Enable = 'off'; end
            else 
                app.flag_busy = false;
                for i = 1:numel(funcsToBeDisabledIfBusy), funcsToBeDisabledIfBusy{i}.Enable = 'on';end
                app.Bn_Record.Value = 0;
                updateFilenameTemplate(app);
            end 
            if ~active
                for i = 1:numel(funcsToBeDisabledIfInactive), funcsToBeDisabledIfInactive{i}.Enable = 'off'; end
            end 
        end %appSetStatus
        function yes = isActive(app)
          if strcmpi(app.status, 'inactive'), yes = false; else, yes = true; end
        end % isActive
        function yes = isBusy(app)
          if strcmpi(app.status, 'busy'), yes = true; app.flag_busy = true; else, yes = false; app.flag_busy = false; end
        end % isActive        
        %%
        function GenFilename(app)
            dateStr = string(app.recordStartTime, app.dateFormat);
            timeStr = string(app.recordStartTime, app.timeFormat);
            fn = app.save_file_prefix;
            if app.Ck_AppendDate.Value, fn = sprintf('%s_%s', strip(fn, 'right', '_'), dateStr); end 
            if app.Ck_AppendTime.Value, fn = sprintf('%s_%s', strip(fn, 'right', '_'), timeStr); end 
            app.Ed_Filename.Value = fn;
            app.FullPathFilename = fullfile(app.OutputPath, app.Ed_Filename.Value); 
            app.AudioFullPathFilename = sprintf('%s%s', app.FullPathFilename, app.audioExt); 
            app.TvdFullPathFilename = sprintf('%s%s', app.FullPathFilename, app.tvdExt); 
            app.AudioFullPathFilename = app.NextAvailableFileName(app.AudioFullPathFilename);
            app.TvdFullPathFilename = app.NextAvailableFileName(app.TvdFullPathFilename);
            [~,fn,~]= fileparts(app.AudioFullPathFilename);
            app.Ed_Filename.Value = fn;
        end%GenFilename
        %% 
        function updateFilenameTemplate(app)
            prefix = app.Ed_FilePrefix.Value;
            exp = '[a-zA-Z_0-9-]+';
            prefix = regexp(prefix, exp, 'match');
            prefix = strjoin(prefix,'');
            app.save_file_prefix = strip(prefix, 'right', '_');
            if isempty(app.save_file_prefix), app.save_file_prefix= '_'; end
            fn = app.save_file_prefix;
            if app.Ck_AppendDate.Value, fn = sprintf('%s_%s', strip(fn, 'right', '_'), app.dateFormat); end 
            if app.Ck_AppendTime.Value, fn = sprintf('%s_%s', strip(fn, 'right', '_'), app.timeFormat); end 
            app.Ed_Filename.Value = fn;          
        end %updateFilenameTemplate
        %%
        function do_log(app, msg)
            if nargin < 2, msg = ''; end
            app.recordStopTimeStr = string(app.recordStopTime, 'HH:mm:ss.SSS');
            app.recordStartTimeStr = string(app.recordStartTime, 'HH:mm:ss.SSS');
            logFullPathFN = fullfile(app.OutputPath, app.logFilename);
            fn = app.Ed_Filename.Value;
            
            try
                fid = fopen(logFullPathFN, 'at'); % append log file
                fprintf(fid,'%s\t%s\t%s\t%s\t%s\n', ...
                    fn, app.recordStartTimeStr, app.recordStopTimeStr, msg, app.Ed_Annotate.Value);
                fclose(fid);
            catch
            end      
            app.Msg1.HTMLSource = sprintf('%s <p> %s <br>File saved. </p>', app.Msg1.HTMLSource, fn);
        end % do_log
        %% 
        function [success, msg] = DetectEchoWave(app)
            success = false; msg = '';
            if ~ispc, msg = 'Error in EchoWave: Not PC!';  return; end
            if ~app.isAdminPC, msg  = 'Error in EchoWave: Not Admin!';  return; end
            if isempty(app.EchoWavePath)
                app.EchoWavePath =  app.FindTelemedEchoWavePath; 
                if isempty(app.EchoWavePath)
                    msg = 'Error in EchoWave: path not found!';  return; 
                end
            end
            app.EchoWaveCmd = sprintf('"%s\\EchoWave.exe"', app.EchoWavePath);
            % [status, tasklist] = system('tasklist'); % this takes about 1 second.
            % if ~contains(tasklist, 'EchoWave'), [status, cmdout] = system(app.EchoWaveCmd); end
            asm_path =  [app.EchoWavePath '\Config\Plugins\AutoInt1Client.dll'];
            asm = NET.addAssembly(asm_path); %#ok<*NASGU> 
            app.asmcmd = AutoInt1Client.CmdInt1();
            if ~isEchoWaveConnected(app), msg = 'EchoWave not connected! (check:AutoInt1Client.dll)'; return; end
            success = true;
            % EchoWaveFreeze(app); 
            if app.num_audio_channels < 2, app.num_audio_channels = 2; end
            [support, msg]=CheckIfAudioDeviceSettingSupported(app);
            if ~support,  msg = 'Audio device not compatible with EchoWave!';  return; end
            [app.ifProbeConnected, msg] = EchoWaveCheckProbe(app);
        end %DetectEchoWave
        %% 
        function [hasProbe, msg] = EchoWaveCheckProbe(app)
            msg = '';
            id_is_no_probe = 223; id_is_unsupported_probe = 224;
            id_get_current_probe_code = 917; id_get_current_probe_name = 918;
            ifNoProbe = app.asmcmd.ParamGetBool(id_is_no_probe);
            ifProbeUnsupported = app.asmcmd.ParamGetBool(id_is_unsupported_probe);
            hasProbe = ~ifNoProbe && ~ifProbeUnsupported; 
            if ~hasProbe, msg = 'probe not found!'; end
            % probe_code = app.asmcmd.ParamGetInt(id_get_current_probe_code);
            % probe_name = app.asmcmd.ParamGetString(id_get_current_probe_name);
        end %EchoWaveCheckProbe
        %% 
        function EchoWaveFreeze(app)
            app.asmcmd.ParamSet(app.id_scanning_state_set, app.id_state_b_b1f);  % Freeze
        end %EchoWaveFreeze
        %%
        function info = EchoWaveGetTVDinfo(app, tvdFilename)
            info = [];
            s = app.asmcmd.OpenFile(tvdFilename);
            if s > 0, return;end
            info.nFrames = double(app.asmcmd.GetFramesCount());
            app.asmcmd.GoToFrame1n(info.nFrames, true);
            info.dur = app.asmcmd.GetCurrentFrameTime(); % msec.
            info.dur  = double(info.dur /1000); % to sec.
            if info.nFrames > 0 && info.dur > 0
                info.fps = info.nFrames / info.dur;
            end
            info.frameWidth = app.asmcmd.GetLoadedFrameWidth();
            info.frameHeight = app.asmcmd.GetLoadedFrameHeight();
        end % 
        %%
        function out = isEchoWaveConnected(app)
            stat = app.asmcmd.ConnectToRunningProgram(); 
            if (stat ~= 0), out = false;  return; else, out = true; end
        end %isEchoWaveConnected
        %%
        function ConvertTVDtoMP4(app, outPath)
            if nargin < 2 || isempty(outPath), outPath = app.OutputPath; end
            outPath = ['"' strip(outPath, 'both', '"'), '"'];
            fromFormat = 'tvd'; toFormat = 'mp4';
            convertFmtCmd = [app.EchoWaveCmd ' -convert_directory ' outPath ' ' fromFormat ' ' toFormat]; 
            [status,cmdout] = system(convertFmtCmd, '-echo'); %#ok<*ASGLU>        
        end %ConvertTVDtoMP4   
        function ConvertTVDtoVideo(app, outPath, toFormat)
            % toFormat: video output formats: avi_comp, avi, avi_cust, avi_wmv9, mp4, dcm_jpeg_cine, dcm_cine
            if nargin < 2 || isempty(outPath), outPath = app.OutputPath; end
            outPath = ['"' strip(outPath, 'both', '"'), '"'];
            fromFormat = 'tvd'; 
            convertFmtCmd = [app.EchoWaveCmd ' -convert_directory ' outPath ' ' fromFormat ' ' toFormat]; 
            [status,cmdout] = system(convertFmtCmd, '-echo'); %#ok<*ASGLU>        
        end %ConvertTVDtoMP4           
        %%
        function [success, msg] = selectAudioDevice(app, devID)
            %% select audio device:            
            msg = ''; devs = audiodevinfo;  
            if isempty(devs) || isempty(devs.input),  success = false; app.audio_dev_idx =[]; msg = 'No audio device!'; return; end
            input_devs = devs.input; 
            dev_names = {input_devs.Name}'; device_IDs = cell2mat({input_devs.ID})';
            %% 
            if nargin < 2 || isempty(devID)
                listString = dev_names; prompt = {'Select audio device:'};
                [ind, ifSelected] = listdlg('PromptString',prompt,...
                    'SelectionMode','single','ListString', listString, ...
                    'ListSize', [480, 100], ...
                    'InitialValue', find(ismember(device_IDs, app.audio_dev_idx)));
                figure(app.UIFigure) % Get focus back on UIFigure
                if ~ifSelected, success = false; app.audio_dev_idx =[]; app.audio_dev_name =''; msg = '<p></p>'; return;  end
                devID = device_IDs(ind);
            end %isempyt(devID)
            ind = find(ismember(device_IDs, devID));
            if ~any(ismember(device_IDs, devID)), success = false; app.audio_dev_idx =[]; app.audio_dev_name =''; msg = 'Wrong audio device ID!'; return; end  
            app.audio_dev_idx = devID;   app.audio_dev_name = dev_names{ind};  
            success = true; 
            if ~success, appSetStatus(app, 'inactive'); end
            figure(app.UIFigure) % Get focus back on UIFigure
        end  %selectAudioDevice
        function [support, msg] = CheckIfAudioDeviceSettingSupported(app)
            %% Check if audio settings are supported:
            msg = '';input_IO = 1;  
            if isempty(app.audio_dev_idx), support = false; msg = 'No audio device'; appSetStatus(app, 'inactive'); return; end
            support = audiodevinfo(input_IO, app.audio_dev_idx, app.audio_srate, app.audio_bit_rate, app.num_audio_channels);
            UpdateRefreshAudioStatus(app)
            if ~support
                msg = 'Audio settings not supported! <br>  Check (sampling rate, nChannels...): <br> Setting->Audio settings';
                appSetStatus(app, 'inactive'); return
            end             
        end %CheckIfAudioDeviceSettingSupported
        function UpdateRefreshAudioStatus(app)
            app.Lb_AudioSettings.Text = ...
                    sprintf('[%d kHz; %d channel(s)]', round(app.audio_srate/1000), app.num_audio_channels);
            for i = 1:app.max_audio_sig_lamps
                if app.num_audio_channels >= i 
                    app.("Lp_AudSig" + num2str(i)).Visible = 'on'; 
                else
                    app.("Lp_AudSig" + num2str(i)).Visible = 'off'; 
                end
            end
        end %UpdateRefreshAudioStatus
        %% 
        function toggleEchoWaveFunctions(app, toState)
            % Turn on/off EchoWave related functions according to current conditions.
            % toState = 'on' or 'off'
            EchoWaveFuncs = {app.Ck_ifRecordTelemed; app.Mn_ConvertTVDtoMP4viaAVI; app.Mn_LoadEchoWavePreset};
            nFuncs = numel(EchoWaveFuncs);
            if ~any(ismember({'on', 'off'}, toState)), return;end
            for i = 1: nFuncs, EchoWaveFuncs{i}.Enable = toState; end
        end % toggleEchoWaveFunctions(app)
    end %methods (Access = private)
    %% Below are supporting functions
    %% Call by app.FUNCTIONNAME
    methods (Access = public, Static=true)
        function out = isAdminPC
            if ~ispc, out = false; return; end
            out = System.Security.Principal.WindowsPrincipal(System.Security.Principal.WindowsIdentity.GetCurrent()).IsInRole(System.Security.Principal.WindowsBuiltInRole.Administrator);
        end% isAdminPC
        %%
        function EchoWavePath  = FindTelemedEchoWavePath(varargin)
        % Find the path of installed Echo Wave II in Windows. 
        % Return empty if not found.
        % WR Chen 26OCT2022
        %     Update:  02JUN2023  
        %              Starting from EchoWave 4.2.0, the application
        %              folder changed to Program Files\Telemed\Echo Wave II Application\.
        %              If both older and newer version of EchoWave applications
        %              exist, this script will return the newer version.
        sep = filesep;
        programFilesFolders = {'Program Files', 'Program Files (x86)'};
        EchoWaveFolders = {'Telemed\Echo Wave II Application\EchoWave II', 'Telemed\Echo Wave II'}; nEchoWaveFolders = numel(EchoWaveFolders);
        exeFN = 'EchoWave.exe';
        startDrive = 'C'; endDrive = 'Z';
        found = 0; EchoWavePath = [];
        for i = double(startDrive):double(endDrive)
            drive = [char(i), ':' sep]; 
            for j = 1:numel(programFilesFolders)
                programFilesFolder = programFilesFolders{j};
                for k = 1:nEchoWaveFolders            
                    EchoWaveFolder = EchoWaveFolders{k};
                    pathCandidate = [drive, programFilesFolder, sep, EchoWaveFolder];
                    candEchoWaveExeFN = fullfile(pathCandidate, exeFN);
                    if exist(candEchoWaveExeFN, 'file')
                        found = 1; EchoWavePath = pathCandidate; return; %#ok<*NASGU> 
                    end
                end
            end
        end
        % if ~found, fprintf('Telemed EchoWave NOT found!\n'); end
        end % FindTelemedEchoWavePath()
        %% 
        function foundPath = FindAppInstalledPath(varargin)
            found = 0; foundPath = []; sep = filesep;
            programFilesFolders = {'Program Files', 'Program Files (x86)'};
            appFolders = {'MewRecorder\application'};  exeFN = 'MewRecorder.exe';
            startDrive = 'C'; endDrive = 'Z';   
            for i = double(startDrive):double(endDrive)
                drive = [char(i), ':' sep]; 
                for j = 1:numel(programFilesFolders)
                    programFilesFolder = programFilesFolders{j};  appFolder = appFolders{1};
                    pathCandidate = [drive, programFilesFolder, sep, appFolder];
                    candExeFN = fullfile(pathCandidate, exeFN);
                    if exist(candExeFN, 'file')
                        found = 1; foundPath = pathCandidate; return; %#ok<*NASGU> 
                    end              
                end
            end            
        end %  FindAppInstalledPath(varargin)
        %%
        function nextFileName = NextAvailableFileName(filename)
            % Find next available filename.
            % Example: 
            % if 'abc.txt', 'abc(1).txt', 'abc(2).txt' exist, 
            % then myNextFileName('abc.txt') will return 'abc(3).txt'
            % W.Chen 06MAR2023
            if ~exist(filename, 'file'), nextFileName = filename; return; end
            [p, f, e] = fileparts(filename); 
            i = 1; upperLimit = 100;   fn = sprintf('%s(%d)%s', f, i, e);   nextFileName = fullfile(p, fn);
            while exist(nextFileName, 'file') && i < upperLimit
                i = i+1; fn = sprintf('%s(%d)%s', f, i, e); nextFileName = fullfile(p, fn);  
            end
            if i >=upperLimit, nextFileName = []; end
        end % NextAvailableFileName  
        %%
        function [filelist, folder] = mygfl(mask, keepExt)
            if nargin<2, keepExt = 1; end
            FLs = dir(mask);   ix = ~cell2mat({FLs.isdir});  FLs = FLs(ix);  filelist = {FLs.name}'; folder = {FLs.folder}';
            if ~keepExt, for fi = 1:numel(filelist), [p, f, e]=fileparts(filelist{fi});   filelist{fi} = f;  end; end
        end % mygfl   
        %%
        function  [success, msg]=deleteFolder(folderPath)
            success = false; msg = '';
            tmpfl = mygfl([folderPath filesep '*.*'],1);
            if ~isempty(tmpfl)
                for i = 1:numel(tmpfl),  tmpFN = fullfile(folderPath, tmpfl{i});  delete(tmpFN);  end
            end
            [success, msg] = rmdir(folderPath);
            function [filelist, folder] = mygfl(mask, keepExt)
                if nargin<2, keepExt = 1; end
                FLs = dir(mask);   ix = ~cell2mat({FLs.isdir});  FLs = FLs(ix);  filelist = {FLs.name}'; folder = {FLs.folder}';
                if ~keepExt, for fi = 1:numel(filelist), [p, f, e]=fileparts(filelist{fi});   filelist{fi} = f;  end; end
            end % mygfl            
        end %deleteFolder
        %%    
function [sig, sr, locs, frameStart, sync_pulses] = TrimTelemedAudio(FName, resampleRate, outFName, fps, sync_channel, minInitialLag)
        % Trim the begining of Telemed two-channel audio, using the sync-pulse
        % signals. 
        % WR Chen 19DEC2022
        %          14FEB2026: Made compatible with AAA sync-stretch box. 
        if nargin < 2, resampleRate = [];end
        if nargin < 3, outFName = []; end
        if nargin < 4, fps = []; end
        if nargin < 5 || isempty(sync_channel), sync_channel =2; end
        if nargin < 6 || isempty(minInitialLag), minInitialLag = 0.35; end 
        % minInitialLag: min value for the first true sync pulse.  Usually
        % it takes up to 1s from the start of recording until the scanning
        % of the first frame. 

        if isempty(fps), max_fps = 300; else, max_fps = fps * 1.3; end
        ifplot = 0;
        [input_path, f, ext] = fileparts(FName); sync_out_FName = fullfile(input_path, [f '_sync' ext]); 
        [s, sr]= audioread(FName);
        [locs, audio, raw_sync, sr, pks] = ParseTelemedSyncPulse(s, sr, sync_channel, max_fps, minInitialLag);
        nSamplesAudio = size(audio,1); 
        audioDur = (nSamplesAudio-1) / sr; 
        t = linspace(0, audioDur, nSamplesAudio)';
        sync_pulses = zeros(nSamplesAudio, 1);  indArr = FindClosest_simple(t, locs); sync_pulses(indArr) = 1;
        if ifplot
            close all; H = figure;  hold on;
            plot(t, sync_pulses); plot(locs, pks, 'ro'); plot(t, sync_pulses,'-');      % xlim([0.5,1])
        end
        ss = [audio, raw_sync, sync_pulses];  
        audiowrite(sync_out_FName, ss, sr);
        frameStart = mean(locs(1:2)); % Get the midpoint between the time of starting scanning and the completion of scanning first frame.
        frameDur = median(diff(locs(2:end))); 
        frameEnd = locs(end) + frameDur; 
        frameStartN = floor(frameStart * sr)+1;
        frameEndN = floor(frameEnd * sr)+1;  
        if frameEndN > nSamplesAudio, frameEndN = nSamplesAudio; end
        sig = audio(frameStartN:frameEndN);
        if ~isempty(resampleRate),  sig = resample(sig, resampleRate,sr); sr = resampleRate; end
        if ~isempty(outFName), audiowrite(outFName, sig, sr); end
            function [locs, audio, syncs, sr, pks] = ParseTelemedSyncPulse(s, sr, sync_channel, max_fps, MinPeakHeight, minInitialLag)
                if nargin < 3, sync_channel = 2;end
                if nargin < 4 || isempty(max_fps), max_fps = 300; end  
                if sync_channel == 2, audio_channel = 1; else, audio_channel = 2; end
                if nargin < 5 || isempty(MinPeakHeight), MinPeakHeight = 0.55; end
                if nargin < 6 || isempty(minInitialLag), minInitialLag = 0.35; end
                nChannels = size(s,2);
                for i = 1:nChannels, s1 = s(:,i); s(:,i) = s1 ./ max(abs(s1)); end
                audio = s(:, audio_channel); syncs = s(:, sync_channel); 
                nSamples = length(audio); dur = (nSamples-1) / sr; 
                syncs = syncs ./ max(abs(syncs));   min_fps = 20; 
                [pks, locs] = findpeaks(syncs,sr, 'MinPeakHeight', MinPeakHeight, 'MinPeakDistance', 1/max_fps); 
                ix = locs > minInitialLag; 
                pks = pks(ix); locs = locs(ix);
                intervals = diff(locs); 
            end % ParseTelemedSyncPulse
            function [indArr, distArr] = FindClosest_simple(RefPts, TestPts)
                % [indArr, distArr] = FindClosest(RefPts, TestPts)  % WRC 5FEB26
                %    Find the index of the closest element in 'RefPts' for all elements in 'TestPts'. 
                % INPUT:  RefPts : m (points) x n (dimensions) ;  TestPts: k (points) x n (dimensions)
                % OUTPUT:  indArr: k x 1  indices in 'RefPts';   distArr: k x 1 distances of each element in TestPts to RefPts.
                k = size(TestPts,1); indArr = NaN * zeros(k,1); distArr = NaN * zeros(k,1);
                 for i = 1:k,  p = TestPts(i,:); dists=udist(RefPts, p);  [minDist, ind]= min(dists); indArr(i) = ind;  distArr(i) = minDist; end
                    function d=udist(pt1,pt2)
                        % pt1, pt2 : m (points) x n (dimensions); d : m (points) x 1 vector if pt2 = 1 x n (dims) and pt1 = m (points) x n (dims), output 'd' is the distances from pt2 to each elements in pt1.
                        nPointsPt2=size(pt2,1); nPointsPt1=size(pt1,1); dimsPt1=size(pt1,2); dimsPt2=size(pt2,2);
                        if dimsPt1~=dimsPt2, error('dimensions not match!');end % if dimsPt1~=dimsPt2, return d=0.
                        if nPointsPt2==nPointsPt1, d=sqrt(sum((pt1-pt2).^2,2)); elseif nPointsPt2==1 && nPointsPt1>1, arr1=pt1; arr2=repmat(pt2,nPointsPt1,1);  d=sqrt(sum((arr1-arr2).^2,2)); end
                    end %udist
             end % FindClosest_simple
        end %TrimTelemedAudio
       %%
        function [status,cmdout] = ffmpegCombineVideoAudio(input_video_filename, input_audio_filename, output_video_filename, opt)
            % Combine video and audio using FFMPEG.
            % REQUIRE: FFMPEG properly installed and searchable in the system PATH. 
            % WR Chen  23MAY2023
            % Latest:  15JUN2023 : added pixel format
            arguments
                input_video_filename char {mustBeText}
                input_audio_filename char {mustBeText}
                output_video_filename char {mustBeText}
                opt.vcodec char {mustBeText} = '-c:v libx264'
                opt.acodec char {mustBeText} = '-c:a copy'
                opt.preset char {mustBeText} = '-preset slow'
                opt.crf char {mustBeText} = '-crf 23' % crf: 0:best ~ 51:worst; Default=23; 17~18: visually lossless
                opt.pixel_format char {mustBeText}  = '-pix_fmt yuv420p' 
                opt.ifUseShortest logical = false % if true, the video and audio will be trimmed to whichever is the shortest.
                opt.ffmpegPath char {mustBeText} = ''
            end
            if ~exist(input_video_filename, 'file'), fprintf('Cannot find: %s\n', input_video_filename); return; end
            if ~exist(input_audio_filename, 'file'), fprintf('Cannot find: %s\n', input_audio_filename); return; end
            if opt.ifUseShortest, useShortest = '-shortest'; else, useShortest = ''; end
            % check FFMPEG:
            if ~isempty(opt.ffmpegPath)
                ffmpegExe = ['"', fullfile(opt.ffmpegPath, 'ffmpeg'), '"'];
            else
                ffmpegExe = 'ffmpeg';
            end
            %%   
            aFn = input_audio_filename; vFn = input_video_filename;
            outFn = output_video_filename;
            command = sprintf(...
             '%s -i "%s" -i "%s" -map 0:v -map 1:a %s %s %s %s %s -strict -2 %s -y "%s"', ...
                ffmpegExe, vFn, aFn, opt.vcodec, opt.pixel_format, opt.preset, opt.crf, opt.acodec, useShortest, outFn);
            % tic
            [status,cmdout] = system(command,'-echo');
            % toc    
        end % ffmpegCombineVideoAudio       
        %%
        function [status,cmdout] = ffmpegConvertOneFile_simple(input_video_file, output_video_file, opt)
            % Convert one video file to another format, using FFMPEG. 
            % Assuming input video file exists, output file doesn't exist, and ffmpeg is installed. 
            % Usage:  ffmpegConvertOneFile_simple(input_video, output_video);
            %  Using optional arguments: 
            %       e.g.:  ffmpegConvertOneFile_simple('1.avi', '1.mp4', input_path = '/desktop', acodec = 'copy');
            %       or      ffmpegConvertOneFile_simple('1.avi', '1.mp4', 'acodec', 'aac');
            % Optional input arguments:  vcodec :  video codec.  Default = 'libx264';   acode  :   audio code.   Default =  'copy'  
            %       crf :     compression quality.  Default = '23' % crf: 1:best ~ 51:worst; Default=23; 17~18: visually lossless
            %       preset :  ffmpeg preset. Default =  'slow'; other options are 'fast', 'medium', 'slower', 'veryslow'. (don't use 'fast' unless for testing)
            % Wei-Rong Chen  06FEB2024
            arguments
                input_video_file char {mustBeText} = '1.avi';
                output_video_file char {mustBeText} = '1.mp4';
                opt.vcodec char {mustBeText} = ' libx264'
                opt.acodec char {mustBeText} = 'copy'
                opt.preset char {mustBeText} = 'slow'
                opt.crf char {mustBeText} = '23' % crf = 0:best ~ 51:worst; Default=23; 17~18: visually lossless
                opt.pixel_format char {mustBeText}  = '-pix_fmt yuv420p'  % don't change this!
            end
            opt.vcodec = strtrim(strrep(lower(opt.vcodec), '-c:v', '')); if ~isempty(opt.vcodec), opt.vcodec = ['-c:v ' opt.vcodec]; end
            opt.acodec = strtrim(strrep(lower(opt.acodec), '-c:a', ''));  if ~isempty(opt.acodec), opt.acodec = ['-c:a ' opt.acodec]; end
            opt.preset = strtrim(strrep(lower(opt.preset), '-preset', ''));  if ~isempty(opt.preset), opt.preset = ['-preset ' opt.preset]; end
            opt.crf = strtrim(strrep(lower(opt.crf), '-crf', '')); if ~isempty(opt.crf), opt.crf = ['-crf ' opt.crf]; end
             vFn = input_video_file; outFn = output_video_file;
            command = sprintf('ffmpeg -i "%s" %s %s %s %s %s -y "%s"', vFn, opt.vcodec, opt.pixel_format, opt.preset, opt.crf, opt.acodec, outFn);
            tic;  [status,cmdout] = system(command,'-echo'); toc
            if status ~= 0 || contains(cmdout, 'Conversion failed'), fprintf('\nFailed to convert the following files:\n'); end
        end % ffmpegConvertOneFile_simple        
        %%
        function [found, cmdout, version] = hasFFmpegInstalled(ffmpegPath)
                if nargin > 0 && ~isempty(ffmpegPath),  ffmpegExe = ['"', fullfile(ffmpegPath, 'ffmpeg'), '"']; else, ffmpegExe = 'ffmpeg'; end
                cmd  = sprintf('%s -version', ffmpegExe); [status,cmdout] = system(cmd , '-echo'); if contains(cmdout, 'ffmpeg') && contains(cmdout, 'version'), found = true; else,  found= false; cmdout = 'ffmpeg not found'; end
                exp = 'ffmpeg\s+version\s+(\S+)\s+'; version = '';
                if found, v = regexp(cmdout, exp, 'tokens'); if ~isempty(v) && iscell(v), version = v{1}{1}; end;end
        end % hasFFmpegInstalled
        %%
    end % methods (Access = public, Static=true)

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.appStartUpTime = datetime('now');
            app.UIFigure.Name = sprintf('%s %s', app.AppName, app.Version);
            if app.isAdminPC, app.UIFigure.Name = [app.UIFigure.Name, '  Admin']; end
            if verLessThan('matlab', app.minMatlabVer)
                appSetStatus(app, 'busy'); msg = sprintf('<p>Failed to start. <br> Requires MATLAB %s or newer.</p>', app.minMatlabVerName);
                app.Msg1.HTMLSource = msg;  return
            end
            [app.appPath, f, e] = fileparts(mfilename('fullpath'));
            % if using compiled app.
            if contains(app.appPath, 'AppData\Local')  
                app.appPath = app.FindAppInstalledPath; 
            end

            % app.ffmpegFullPathFilename = fullfile(app.appPath, app.ffmpegFilename);
            % if exist(app.ffmpegFullPathFilename, 'file'), app.ffmpegFound = true; end
            [app.ffmpegFound, ~, app.ffmpegVersion] = app.hasFFmpegInstalled();
            [success, msg] = initialize(app);
            if ~success
                appSetStatus(app,'inactive'); app.Msg1.HTMLSource = msg;  return
            end
            app.Bn_SelectOutputFolder.Text = app.OutputPath;
            [success, msg] = CheckOutputFolderAccess(app);
            if ~success, appSetStatus(app,'inactive'); app.Msg1.HTMLSource = msg; return; end
            %% Success:
            
            app.save_file_prefix = strip(strtrim(app.Ed_FilePrefix.Value), 'right', '_');
            app.audioExt = ['.' strip(app.audioExt, 'left', '.')]; 
            app.Msg1.HTMLSource = '<p>Ready</p>';
            updateFilenameTemplate(app);
            app.Lb_MaxTimeOut.Text = sprintf('Max time out = %s', ...
                string(duration(0, 0, app.maxTimeOut), 'hh:mm:ss'));            
            app.Ck_RecordByDurValueChanged(app);
            appSetStatus(app,'ready'); 
        end

        % Value changed function: Bn_Record
        function StartRecordButtonPushed(app, event)

            if app.isRecording, return; end
            app.isRecording = true;
            app.Bn_Record.Enable = 'off';            
            if app.Ck_ifRecordTelemed.Value
                if ~isEchoWaveConnected(app), app.isRecording =false; app.Msg1.HTMLSource = '<p> Echo Wave disconnected! </p>'; app.Bn_Record.Enable = 'on'; return;   end
                if ~app.ifProbeConnected,  app.isRecording =false; app.Msg1.HTMLSource = '<p> Echo Wave disconnected! </p>';  app.Bn_Record.Enable = 'on'; return; end
            end           
            appSetStatus(app, 'busy');
            app.Bn_Stop.Enable = 'on';  
            app.recordStartTime = datetime('now');
            app.Msg1.HTMLSource = '<p>Recording...</p>'; 
            GenFilename(app);            
            %% audiorecorder:
            audioRecordedSuccess = true;
            try
                app.audioRecObj= audiorecorder(app.audio_srate, app.audio_bit_rate, app.num_audio_channels, app.audio_dev_idx);
                record(app.audioRecObj); pause(app.timerPeriod);
            catch
                app.isRecording= false; audioRecordedSuccess = false; 
                app.Bn_Stop.Enable = 'off'; app.Bn_Record.Enable = 'on';
                appSetStatus(app, 'ready');
                app.Msg1.HTMLSource = '<p>Error initiating audio recording!</p>';
                return
            end
            %% Telemed Record:
            if app.Ck_ifRecordTelemed.Value
                EchoWaveFreeze(app);
                EchoWaveStartRecord(app); 
            end
            %% Recording timer:
            dur = 0; audioSignalLength = 0; previousAudioSignalLength = 0;
            Msg1HTML = 'Ready.';
            while app.Bn_Record.Value
                currentTime = datetime('now'); durObj = currentTime - app.recordStartTime;              
                dur = round(seconds(durObj), 3); 
                app.Ed_TimeRecorded.Value = string(durObj, 'hh:mm:ss.SSS');
                try
                    pause(app.timerPeriod);
                    S = getaudiodata(app.audioRecObj); s = S(end,:); 
                    previousAudioSignalLength = audioSignalLength;
                    audioSignalLength = size(S,1);  
                    audioSignalLengthDiff = audioSignalLength - previousAudioSignalLength;
                catch
                    app.isRecording= false; audioRecordedSuccess = false; break 
                end
                if audioSignalLengthDiff < 1, app.isRecording= false; audioRecordedSuccess = false; Msg1HTML = ''; break; end
                if s(1) ~= 0, app.Lp_AudSig1.Color = app.hasSignalColor; end
                if size(s, 2) > 1 && s(2) ~= 0, app.Lp_AudSig2.Color = app.hasSignalColor; end
                if size(s, 2) > 2 && s(3) ~= 0, app.Lp_AudSig3.Color = app.hasSignalColor; end
                if size(s, 2) > 3 && s(4) ~= 0, app.Lp_AudSig4.Color = app.hasSignalColor; end
                if dur > app.maxTimeOut
                    app.Msg1.HTMLSource = '<p>Max time out!</p>';
                    app.isRecording= false; break
                end
                if app.Ck_RecordByDur.Value && dur > app.Ed_RecordDur.Value
                    app.isRecording= false; break
                end
            end % while app.isRecording
            app.Bn_Stop.Enable = 'off'; 

            app.Lp_AudSig1.Color = app.noSignalColor; app.Lp_AudSig2.Color = app.noSignalColor;
            % Stop recording:
            if app.Ck_ifRecordTelemed.Value
                app.asmcmd.ParamSet(app.id_scanning_state_set, app.id_state_b_b1f);  % Freeze
            end
            try
                stop(app.audioRecObj); 
            catch
                audioRecordedSuccess = false; 
            end
            app.recordStopTime = datetime('now');   
            % Save data:
            if app.Ck_ifRecordTelemed.Value, EchoWaveStopAndSave(app); end
            
            if audioRecordedSuccess
                success = SaveAudio(app); 
                if success
                     if app.Ck_ifRecordTelemed.Value
                         msg = 'success-US-audio';
                     else
                         msg = 'success-audio';
                     end
                else
                    msg = 'write-audio-failed';     
                    Msg1HTML = '<p>Fail to write audio file! <br>Permission denied. <br> Try another output folder.</p>';
                end 
            else 
                Msg1HTML = '<p>Error in audio recording!'; 
                msg = 'audio-error';
            end
            do_log(app, msg);  
            %%       
            appSetStatus(app, 'ready');
            app.Msg1.HTMLSource = Msg1HTML; 
            %% test
            % app.asmcmd.GoToFrame1n(nFrames, true);
            % img = uint8(app.asmcmd.GetLoadedFrameGray());
        end

        % Button pushed function: Bn_Stop
        function Bn_StopPushed(app, event)
            if ~app.isRecording, return; end
            app.Bn_Stop.Enable = 'off';   app.isRecording = false;
            app.Bn_Record.Value = false;
            app.recordStopTime = datetime('now'); app.Msg1.HTMLSource = '<p>Stopped</p>';     
        end

        % Value changed function: Ck_AppendDate, Ck_AppendTime, 
        % ...and 1 other component
        function Ed_FilePrefixValueChanged(app, event)
            if app.flag_busy, return; end
            updateFilenameTemplate(app)
        end

        % Button pushed function: Bn_SelectOutputFolder
        function Mn_SelectOutputFolderMenuSelected(app, event)
            % UI select output folder:
            if app.flag_busy, return; end
            selpath = uigetdir(app.OutputPath, 'Select output folder');
            figure(app.UIFigure); % Get focus back to main figure
            if isequal(selpath, 0), return; end % user canceled
            [success, msg] = CheckOutputFolderAccess(app, selpath);
            if ~success
                uialert(app.UIFigure, msg,'Access error'); 
                return;
            end
            app.Bn_SelectOutputFolder.Text = selpath;
            app.OutputPath = selpath;
            cd(selpath);
            if ~app.isActive, initialize(app);end
        end

        % Menu selected function: Mn_SetMaxTimeOut
        function Mn_SetMaxTimeOutMenuSelected(app, event)
            if app.flag_busy, return; end
            defaultAnswer = {sprintf('%d', app.maxTimeOut)};
            answer = inputdlg('Set max time out in seconds (< 36000s [10 hours]):',...
             'Set max time out', [1 100], defaultAnswer);
            figure(app.UIFigure); % Get focus back to main figure
            isValid = true;
             if isempty(answer) || isnan(str2double(answer{1}))
                 app.Msg1.HTMLSource = 'Invalid max time out!'; return;
             end
            answer = round(str2double(answer{1}));
            if answer < 1 || answer > 36000, isValid = false; end
            if isValid               
                app.maxTimeOut = answer;
                app.Msg1.HTMLSource = '<p>Ready</p>';
                app.Lb_MaxTimeOut.Text = sprintf('Max time out = %s', ...
                    string(duration(0, 0, app.maxTimeOut), 'hh:mm:ss'));
            else
                app.Msg1.HTMLSource = 'Invalid max time out!';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % if app.isRecording, return;end
            msg = '';
            msg = {msg, sprintf(' Close %s? ', app.AppName)};
            selection = uiconfirm(app.UIFigure, msg,'Quit', 'Icon','warning');
            switch selection
                case 'OK', 
                    if app.isRecording
                        app.Bn_Record.Value = false;
                        try
                            if app.Ck_ifRecordTelemed.Value
                                EchoWaveFreeze(app);
                            end                    
                            stop(app.audioRecObj);
                        catch
                        end
                    end
                    app.delete(); 
                case 'Cancel', 
                    return
            end            
        end

        % Value changed function: Ck_RecordByDur
        function Ck_RecordByDurValueChanged(app, event)
            if app.Ck_RecordByDur.Value
                app.Ed_RecordDur.Enable = 'on';
            else
                app.Ed_RecordDur.Enable = 'off';
            end
        end

        % Menu selected function: Mn_SelectAudioDevice
        function Mn_SelectAudioDeviceSelected(app, event)
            if app.flag_busy, return; end
            [success, msg] = selectAudioDevice(app);
            if ~success, app.Msg1.HTMLSource = msg; return; end 
            [support, msg] = CheckIfAudioDeviceSettingSupported(app);
            if ~support, app.Msg1.HTMLSource = msg; return; end 
            appSetStatus(app, 'ready');
        end

        % Menu selected function: Mn_AudioInfo
        function Mn_AudioInfoMenuSelected(app, event)
            if app.flag_busy, return;end
            title = 'Audio device information';
            if isempty(app.audio_dev_idx)
                msg = 'No audio device.';
            else
                devs = audiodevinfo;  input_devs = devs.input; 
                dev_names = {input_devs.Name}'; device_IDs = cell2mat({input_devs.ID})';   
                ind = find(ismember(device_IDs, app.audio_dev_idx));
                dev_name = dev_names{ind};
                msg = sprintf(...
                ['Audio Device ID: %d\n' ...
                'Audio Device Name:\n%s\n' ...
                'Sampling Rate (Hz): %d\n' ...
                'bit rate: %d\n' ...
                'Number of channels: %d\n'], ...
                app.audio_dev_idx, dev_name, app.audio_srate, app.audio_bit_rate, app.num_audio_channels);                
            end
            uialert(app.UIFigure, msg, title, 'icon', 'info');             
        end

        % Menu selected function: Mn_About
        function Mn_AboutMenuSelected(app, event)
            if app.flag_busy, return;end
            title = '';
            sinceYear = 2023;
            currentYear = year(datetime);
            cInfo = sprintf('Copyright (C) %04d-%04d Wei-Rong Chen', sinceYear, currentYear); 
            email = '<wei-rong.chen[AT]yale.edu>';
            licInfo = 'This program is free software under GNU General Public License, version 3. This program is distributed WITHOUT ANY WARRANTY AND SUPPORT. See the GNU General Public License for more details.';
            msg = sprintf('%s\n Version: %s \n App Path: %s\n Latest Update: %s \n\n %s \n %s\n %s', ...
            app.AppName, app.Version, app.appPath,  app.Update, cInfo, email, licInfo);  
            if app.ffmpegFound,  msg = sprintf('%s \n FFMPEG version: %s', msg, app.ffmpegVersion);  end
            uialert(app.UIFigure, msg, title, 'icon', 'info');  
        end

        % Value changed function: Ck_ifRecordTelemed
        function Ck_ifRecordTelemedValueChanged(app, event)
            if app.isRecording, return;end 
            initialize(app);
        end

        % Menu selected function: Mn_AudioSettings
        function Mn_AudioSettingsMenuSelected(app, event)

            defaultAnswer = {sprintf('%d', app.audio_srate), sprintf('%d', app.num_audio_channels)};
            questions = {'Audio sampling rate (Hz)', 'Number of audio channels'};
            answers = inputdlg(questions,...
             '',1, defaultAnswer);
            figure(app.UIFigure); % Get focus back to main figure
            if isempty(answers), return; end % user cancelled
            app.Msg1.HTMLSource = 'Ready.';
            %% Samping rate:
            ans1 = round(str2double(answers{1}));
            if ~isnan(ans1) && ans1 >= 16000 && ans1 <= 96000
                app.audio_srate = ans1;
                app.Msg1.HTMLSource = ...
                   sprintf('%s <br> Set sampling rate to %d Hz.', app.Msg1.HTMLSource, ans1);
            else
                 app.Msg1.HTMLSource = ...
                   sprintf('%s <br> Unsupported sampling rate.', app.Msg1.HTMLSource, ans1);   
            end %
            %% Number of audio channels:
            current_num_audio_channels = app.num_audio_channels; % store the current state of num_audio_channels.
            ans2 = round(str2double(answers{2}));
            if isnan(ans2) || ans2 < 1
                app.Msg1.HTMLSource = ...
                   sprintf('%s <br> Unsupported number of channels.', app.Msg1.HTMLSource);
                return
            end
            app.num_audio_channels = ans2;
            [support, msg] = CheckIfAudioDeviceSettingSupported(app);
            if ~support 
                app.num_audio_channels = current_num_audio_channels;  % change back to the previous state.
                UpdateRefreshAudioStatus(app);
                app.Msg1.HTMLSource = msg; 
                return; 
            end 
            app.Msg1.HTMLSource = ...
               sprintf('%s <br> Set number of audio channgels to %d.', app.Msg1.HTMLSource, ans2);
            appSetStatus(app, 'ready');  
        end

        % Menu selected function: Mn_ConvertTVDtoMP4viaAVI
        function Mn_ConvertTVDtoVideoMenuSelected(app, event)
            if app.flag_busy, return; end
            previous_status = app.status; appSetStatus(app, 'busy'); 
            [success, msg] = DetectEchoWave(app);   
            toFormat = event.Source.UserData.toFormat; % mp4 or avi_comp
            videoExt = strrep(toFormat, '_comp','');
            if ~success, uialert(app.UIFigure,msg,'EchoWave error'); appSetStatus(app, previous_status); return;end
           %% Check ffmpeg:
            app.ffmpegFound = app.hasFFmpegInstalled();
            if ~app.ffmpegFound
                msg = {'FFMPEG not found! ', 'TVD will be converted but NOT combined with audio.', 'Synchronized audio will be saved as .flac file.', ' '};
            else
                msg = {''};
            end
            mask1 = sprintf('%s%s*%s', app.OutputPath, filesep, app.tvdExt);            
            % mask2 = sprintf('%s%s*%s', app.OutputPath, filesep, app.audioExt);
            fl = app.mygfl(mask1,0); % fl2 = app.mygfl(mask2,0);
            % ix = ismember(fl, fl2); 
            % if ~any(ix), app.Msg1.HTMLSource = 'No TVD files!'; appSetStatus(app, previous_status); return;end
            if isempty(fl), app.Msg1.HTMLSource = 'No TVD files!'; appSetStatus(app, previous_status); return;end    
            nfl = numel(fl);
            %% Confirm:
            msg = [msg, {sprintf('%d TVD files found.', nfl)}];
            msg = [msg, {'Video conversion takes very long time.', ...
                'Remember to max out Echowave resolution', 'and set MP4 bit rate > 8000 Kbps.', 'Continue?'}];
            selection = uiconfirm(app.UIFigure, msg,'Confirm conversion', 'Icon','warning');
            switch selection, case 'OK',  case 'Cancel',  appSetStatus(app, previous_status); return; end   
            %% Create temp folder:
            tmpPath = fullfile(app.OutputPath, app.tmpFolder);
            [success, ~]=mkdir(tmpPath);
            if ~success, uialert(app.UIFigure,'Failed to create temp folder. Access denied!','Error');  appSetStatus(app, previous_status); return;end

            h_prog = uiprogressdlg(app.UIFigure,'Title','Converting TVD to MP4','Cancelable','on');
            %%
            for ifl = 1:nfl
                % Check for Cancel button press
                if h_prog.CancelRequested,  break;  end
                fn = fl{ifl}; inFn = fullfile(app.OutputPath, fn); toFn =  fullfile(tmpPath, fn); 
                % Update progress, report current estimate
                h_prog.Value = ifl/nfl;  h_prog.Message = sprintf('%d of %d file(s): %s', ifl, nfl, fn);
                %
                tvdFN = [inFn, app.tvdExt]; audioFN = [inFn, app.audioExt];
                tmpTvdFN = fullfile(tmpPath, [fn app.tvdExt]); tmpVideoFN = fullfile(tmpPath, [fn '.' videoExt]); 
                outVideoFN = fullfile(app.OutputPath, [fn '.' 'mp4']);
                [status,msg] = copyfile(tvdFN, tmpTvdFN); 
                info = EchoWaveGetTVDinfo(app,tmpTvdFN);
                if ~isempty(info) && isfield(info, 'fps') && info.fps > 0, fps = info.fps; else, fps = [];  end
                ConvertTVDtoVideo(app, tmpPath, toFormat);

                if exist(audioFN, 'file')
                    sync_channel = app.which_channel_is_telemed_sync; trimmedAudioFN = fullfile(app.OutputPath, [fn '.flac']);
                    [sig, sr, locs, frameStart] = app.TrimTelemedAudio(audioFN, [], trimmedAudioFN, fps, sync_channel);
                    
                    if app.ffmpegFound
                        [status,cmdout] = app.ffmpegCombineVideoAudio(tmpVideoFN, trimmedAudioFN, outVideoFN);
                    else
                        [status,msg] = copyfile(tmpVideoFN, outVideoFN); 
                    end
                else
                    % No audio. TVD only
                    if app.ffmpegFound
                         [status,cmdout] = app.ffmpegConvertOneFile_simple(tmpVideoFN, outVideoFN);
                     else
                        [status,msg] = copyfile(tmpVideoFN, outVideoFN); 
                    end
                end
                delete(tmpTvdFN);   delete(tmpVideoFN); 
            end   
            %% Delete temp folder:
             [success, msg] = app.deleteFolder(tmpPath);           
            appSetStatus(app, previous_status);
            close(h_prog);
        end

        % Menu selected function: Mn_LoadEchoWavePreset
        function Mn_LoadEchoWavePresetSelected(app, event)
            if app.flag_busy, return; end
            if ~app.Ck_ifRecordTelemed.Value, uialert(app.UIFigure, 'CheckBox: "Record Telemed Ultrasound" not selected!', ''); return; end
            previous_status = app.status; appSetStatus(app, 'busy'); 
             [success, msg] = DetectEchoWave(app);
            if ~success, uialert(app.UIFigure,msg, 'EchoWave error');  appSetStatus(app, previous_status); return;end           
            if ~app.ifProbeConnected, uialert(app.UIFigure, 'Probe not found!', 'EchoWave error'); appSetStatus(app, previous_status); return;end  
            EchoWaveFreeze(app);
            beamformer = 'MCE'; probe = 'MC4-2R20S-3';
            preset_name = 'Haskins Tongue';
            % filterspec = {fullfile(app.appPath, 'EchoWave_Preset_Haskins_Tongue.dat'),'Preset .dat file'};
            filterspec = fullfile(app.appPath, '*.dat');
            [fn, fpath] = uigetfile(filterspec, 'Select preset file');
            figure(app.UIFigure); % Get focus back to main figure
            % Make sure user didn't cancel uigetfile dialog
            if isequal(fn,0),   appSetStatus(app, previous_status); figure(app.UIFigure);  return; end  
            [~,~,e]=fileparts(fn); 
            if ~strcmpi(e, '.dat'), uialert(app.UIFigure, 'Wrong EchoWave preset file!','Error');appSetStatus(app, previous_status); figure(app.UIFigure); return; end 
            preset_FullFName = fullfile(fpath, fn);
            cmd = sprintf('%s -import_presets %s "%s" %s "%s"', ...
                app.EchoWaveCmd, beamformer, preset_FullFName, probe, preset_name);
            [status,cmdout] = system(cmd, '-echo'); %#ok<*ASGLU> 
            cmd = sprintf('%s -apply_preset "%s"', ...
                app.EchoWaveCmd, preset_name);
            [status,cmdout] = system(cmd, '-echo'); %#ok<*ASGLU> 
            EchoWaveFreeze(app);
             appSetStatus(app, previous_status);
        end

        % Menu selected function: Mn_CheckFFMPEG
        function Mn_CheckFFMPEGMenuSelected(app, event)
            if app.flag_busy, return;end
            [found, cmdout] = app.hasFFmpegInstalled();  title = 'Check FFMPG'; msg = cmdout; uialert(app.UIFigure, msg, title, 'icon', 'info');                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Mn_Setting = uimenu(app.UIFigure);
            app.Mn_Setting.Text = 'Setting';
            app.Mn_SetMaxTimeOut = uimenu(app.Mn_Setting);
            app.Mn_SetMaxTimeOut.MenuSelectedFcn = createCallbackFcn(app, @Mn_SetMaxTimeOutMenuSelected, true);
            app.Mn_SetMaxTimeOut.Text = 'Set max time out';
            app.Mn_SelectAudioDevice = uimenu(app.Mn_Setting);
            app.Mn_SelectAudioDevice.MenuSelectedFcn = createCallbackFcn(app, @Mn_SelectAudioDeviceSelected, true);
            app.Mn_SelectAudioDevice.Text = 'Select audio device';
            app.Mn_AudioSettings = uimenu(app.Mn_Setting);
            app.Mn_AudioSettings.MenuSelectedFcn = createCallbackFcn(app, @Mn_AudioSettingsMenuSelected, true);
            app.Mn_AudioSettings.Text = 'Audio settings';
            app.Mn_Tools = uimenu(app.UIFigure);
            app.Mn_Tools.Text = 'Tools';
            app.Mn_ConvertTVDtoMP4viaAVI = uimenu(app.Mn_Tools);
            app.Mn_ConvertTVDtoMP4viaAVI.MenuSelectedFcn = createCallbackFcn(app, @Mn_ConvertTVDtoVideoMenuSelected, true);
            app.Mn_ConvertTVDtoMP4viaAVI.Tooltip = {'Convert TVD to AVI in EchoWave. Then combine AVI and audio to MP4 with FFMPEG.'};
            app.Mn_ConvertTVDtoMP4viaAVI.Text = 'Convert All TVD to MP4 (via AVI)';
            app.Mn_LoadEchoWavePreset = uimenu(app.Mn_Tools);
            app.Mn_LoadEchoWavePreset.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadEchoWavePresetSelected, true);
            app.Mn_LoadEchoWavePreset.Text = 'Load EchoWave Preset';
            app.Mn_Info = uimenu(app.UIFigure);
            app.Mn_Info.Text = 'Info';
            app.Mn_AudioInfo = uimenu(app.Mn_Info);
            app.Mn_AudioInfo.MenuSelectedFcn = createCallbackFcn(app, @Mn_AudioInfoMenuSelected, true);
            app.Mn_AudioInfo.Text = 'Audio Info';
            app.Mn_CheckFFMPEG = uimenu(app.Mn_Info);
            app.Mn_CheckFFMPEG.MenuSelectedFcn = createCallbackFcn(app, @Mn_CheckFFMPEGMenuSelected, true);
            app.Mn_CheckFFMPEG.Text = 'Check FFMPEG';
            app.Mn_About = uimenu(app.Mn_Info);
            app.Mn_About.MenuSelectedFcn = createCallbackFcn(app, @Mn_AboutMenuSelected, true);
            app.Mn_About.Text = 'About MewRecorder';
            app.Pn_Filename = uipanel(app.UIFigure);
            app.FilenameprefixLabel = uilabel(app.Pn_Filename);
            app.FilenameprefixLabel.Text = 'Filename:   prefix=';
            app.Ed_FilePrefix = uieditfield(app.Pn_Filename, 'text');
            app.Ed_FilePrefix.ValueChangedFcn = createCallbackFcn(app, @Ed_FilePrefixValueChanged, true);
            app.Ed_FilePrefix.Value = 'recording';
            app.Ck_AppendDate = uicheckbox(app.Pn_Filename);
            app.Ck_AppendDate.ValueChangedFcn = createCallbackFcn(app, @Ed_FilePrefixValueChanged, true);
            app.Ck_AppendDate.Tooltip = {'Append date to filename.'};
            app.Ck_AppendDate.Text = 'append date';
            app.Ck_AppendDate.Value = true;
            app.Ck_AppendTime = uicheckbox(app.Pn_Filename);
            app.Ck_AppendTime.ValueChangedFcn = createCallbackFcn(app, @Ed_FilePrefixValueChanged, true);
            app.Ck_AppendTime.Tooltip = {'Append recording started time stamp to filename.'};
            app.Ck_AppendTime.Text = 'append time';
            app.Ck_AppendTime.Value = true;
            app.FilenameLabel = uilabel(app.Pn_Filename);
            app.FilenameLabel.Text = 'Filename=';
            app.Ed_Filename = uieditfield(app.Pn_Filename, 'text');
            app.Ed_Filename.Editable = 'off';
            app.Ed_Filename.Enable = 'off';
            app.AnnotateEditFieldLabel = uilabel(app.Pn_Filename);
            app.AnnotateEditFieldLabel.Text = 'Annotate: ';
            app.Ed_Annotate = uieditfield(app.Pn_Filename, 'text');
            app.Bn_Stop = uibutton(app.UIFigure, 'push');
            app.Bn_Stop.ButtonPushedFcn = createCallbackFcn(app, @Bn_StopPushed, true);
            app.Bn_Stop.Enable = 'off';
            app.Bn_Stop.Text = 'Stop';
            app.Pn_MsgBox = uipanel(app.UIFigure);
            app.Msg1 = uihtml(app.Pn_MsgBox);
            app.TimeRecordedLabel = uilabel(app.UIFigure);
            app.TimeRecordedLabel.Text = 'Time Recorded:';
            app.Ed_TimeRecorded = uieditfield(app.UIFigure, 'text');
            app.Ed_TimeRecorded.Editable = 'off';
            app.Ed_TimeRecorded.Value = '00:00:00.000';
            app.Ck_ifNormAudio = uicheckbox(app.UIFigure);
            app.Ck_ifNormAudio.Text = 'Normalize audio amplitude';
            app.Ck_ifNormAudio.Value = true;
            app.Bn_SelectOutputFolder = uibutton(app.UIFigure, 'push');
            app.Bn_SelectOutputFolder.ButtonPushedFcn = createCallbackFcn(app, @Mn_SelectOutputFolderMenuSelected, true);
            app.Bn_SelectOutputFolder.Tooltip = {'Click to select folder.'};
            app.Bn_SelectOutputFolder.Text = 'output folder';
            app.CurrentfolderLabel = uilabel(app.UIFigure);
            app.CurrentfolderLabel.Text = 'Current folder:';
            app.Lb_MaxTimeOut = uilabel(app.UIFigure);
            app.Lb_MaxTimeOut.Text = 'Max time out = 00:00:00';
            app.Ck_RecordByDur = uicheckbox(app.UIFigure);
            app.Ck_RecordByDur.ValueChangedFcn = createCallbackFcn(app, @Ck_RecordByDurValueChanged, true);
            app.Ck_RecordByDur.Text = 'Record by duration (sec.): ';
            app.Ed_RecordDur = uieditfield(app.UIFigure, 'numeric');
            app.Ed_RecordDur.Limits = [0.5 7200];
            app.Ed_RecordDur.ValueDisplayFormat = '%11.1f';
            app.Ed_RecordDur.Value = 5;
            app.Ck_ifRecordTelemed = uicheckbox(app.UIFigure);
            app.Ck_ifRecordTelemed.ValueChangedFcn = createCallbackFcn(app, @Ck_ifRecordTelemedValueChanged, true);
            app.Ck_ifRecordTelemed.Tooltip = {'Record Telemed ultrasound video via EchoWave'};
            app.Ck_ifRecordTelemed.Text = 'Record Telemed Ultrasound';
            app.Lb_AudioSettings = uilabel(app.UIFigure);
            app.Lb_AudioSettings.Text = 'Num audio channels = 2';
            app.Bn_Record = uibutton(app.UIFigure, 'state');
            app.Bn_Record.ValueChangedFcn = createCallbackFcn(app, @StartRecordButtonPushed, true);
            app.Bn_Record.Text = 'Start Record';
            app.Lp_AudSig4 = uilamp(app.UIFigure);
            app.Lp_AudSig3 = uilamp(app.UIFigure);
            app.Lp_AudSig2 = uilamp(app.UIFigure);
            app.Lp_AudSig1 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MewRecorder

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end